classdef ExtractCarPlateNumber < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        PlateNumberTextArea       matlab.ui.control.TextArea
        PlateNumberTextAreaLabel  matlab.ui.control.Label
        NegateBinaryCheckBox      matlab.ui.control.CheckBox
        BrowseImageButton         matlab.ui.control.Button
        CarPlateImageContainer    matlab.ui.control.UIAxes
        BinaryImageContainer      matlab.ui.control.UIAxes
        GrayImageContainer        matlab.ui.control.UIAxes
        OriginalImageContainer    matlab.ui.control.UIAxes
    end


    properties (Access = public)
        original_image
        gray_image
        binary_image
        car_plate_image
        negate_bin
        template
    end

    methods (Access = public)
        function updateimage(app, fname)
            try
                app.original_image = imread(fname);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end

            displayImages(app)
        end
        
        function displayImages(app)
            if isempty(app.original_image) 
                return 
            end

            imagesc(app.OriginalImageContainer, app.original_image);
            displayGray(app);
            displayBinary(app);
            displayCarPlate(app);
        end

        function displayGray(app)
            if isempty(app.original_image) 
                return 
            end

            image = app.original_image;
            app.gray_image = rgb2gray(image);
            imagesc(app.GrayImageContainer, app.gray_image);
        end

        function displayBinary(app)
            if isempty(app.gray_image) 
                return 
            end

            image = app.gray_image;
            app.binary_image = imbinarize(image);
            if app.negate_bin
                app.binary_image = ~app.binary_image;
            end
            imagesc(app.BinaryImageContainer, app.binary_image);
        end

        function displayCarPlate(app)
            if isempty(app.original_image) || isempty(app.gray_image) || isempty(app.binary_image)
                return 
            end

            [plate_num, app.car_plate_image] = extract_car_plate(app.original_image, app.binary_image, app.template);
            app.PlateNumberTextArea.Value = plate_num;
            imagesc(app.CarPlateImageContainer, app.car_plate_image);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.OriginalImageContainer.Colormap = gray(256);
            axis(app.OriginalImageContainer, 'image');
            app.GrayImageContainer.Colormap = gray(256);
            axis(app.GrayImageContainer, 'image');
            app.BinaryImageContainer.Colormap = gray(256);
            axis(app.BinaryImageContainer, 'image');
            app.CarPlateImageContainer.Colormap = gray(256);
            axis(app.CarPlateImageContainer, 'image');

            app.template = create_template();
        end

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);

            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname); % TO DO
            end
        end

        % Value changed function: NegateBinaryCheckBox
        function NegateBinaryCheckBoxValueChanged(app, event)
            app.negate_bin = app.NegateBinaryCheckBox.Value;
            app.displayImages();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.OriginalImageContainer = uiaxes(app.GridLayout);
            title(app.OriginalImageContainer, 'Original Image')
            app.OriginalImageContainer.XLim = [0 1];
            app.OriginalImageContainer.XTick = [];
            app.OriginalImageContainer.YTick = [];
            app.OriginalImageContainer.ZTick = [];
            app.GrayImageContainer = uiaxes(app.GridLayout);
            title(app.GrayImageContainer, 'Gray Image')
            app.GrayImageContainer.XLim = [0 1];
            app.GrayImageContainer.XTick = [];
            app.GrayImageContainer.YTick = [];
            app.GrayImageContainer.ZTick = [];
            app.BinaryImageContainer = uiaxes(app.GridLayout);
            title(app.BinaryImageContainer, 'Binary Image')
            app.BinaryImageContainer.XLim = [0 1];
            app.BinaryImageContainer.XTick = [];
            app.BinaryImageContainer.YTick = [];
            app.BinaryImageContainer.ZTick = [];
            app.CarPlateImageContainer = uiaxes(app.GridLayout);
            title(app.CarPlateImageContainer, 'Exctracted Plate Number Image')
            app.CarPlateImageContainer.XLim = [0 1];
            app.CarPlateImageContainer.XTick = [];
            app.CarPlateImageContainer.YTick = [];
            app.CarPlateImageContainer.ZTick = [];
            app.BrowseImageButton = uibutton(app.GridLayout, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Text = 'Browse Image';
            app.NegateBinaryCheckBox = uicheckbox(app.GridLayout);
            app.NegateBinaryCheckBox.ValueChangedFcn = createCallbackFcn(app, @NegateBinaryCheckBoxValueChanged, true);
            app.NegateBinaryCheckBox.Text = 'Negate Binary';
            app.PlateNumberTextAreaLabel = uilabel(app.GridLayout);
            app.PlateNumberTextAreaLabel.Text = 'Plate Number';
            app.PlateNumberTextArea = uitextarea(app.GridLayout);
            app.PlateNumberTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExtractCarPlateNumber

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end